classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        mmEditField       matlab.ui.control.NumericEditField
        mmEditFieldLabel  matlab.ui.control.Label
        Button_2          matlab.ui.control.Button
        zmmLabel          matlab.ui.control.Label
        dmmLabel          matlab.ui.control.Label
        fmmLabel          matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        Label_4           matlab.ui.control.Label
        umEditField       matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        Button            matlab.ui.control.Button
        Lens_Axes         matlab.ui.control.UIAxes
        dst_Axes          matlab.ui.control.UIAxes
        src_Axes          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Lens=[0.200,0.15,0.300
            0.100,0.10,0.600]; %透镜参数
        img_name=[];            %图像名字
        img_path=[];            %图像路径
        img=imread("test1.png");%初始图像
        lambda = 632.8*1e-9;    %波长
        pix_size = 5e-6;        %像素尺寸
        free_prop_distance = 100*1e-3;%自由传播距离
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            % 读取 TextArea 全部内容
            lens_text = app.TextArea.Value;          % cellstr，每行一个字符串
            lens_text = strtrim(lens_text);          % 去掉首尾空白
            lens_text = lens_text(~cellfun('isempty',lens_text)); % 删掉空行
            
            n_total   = numel(lens_text);            % 原始总行数
            Lens_valid = [];                         % 合法透镜参数矩阵
            valid_line = cell(0);                    % 记录合法原始字符串
            
            for i = 1:n_total
                line_str = lens_text{i};
                tokens   = strsplit(line_str, ',');  % 用逗号分割
                if numel(tokens) < 3
                    fprintf('第%d行参数不足，已跳过：%s\n',i,line_str);
                    continue
                end
                
                % 尝试转数值
                f = str2double(strtrim(tokens{1}));
                D = str2double(strtrim(tokens{2}));
                z = str2double(strtrim(tokens{3}));
                
                if any(isnan([f D z])) || any([f D z] <= 0)
                    fprintf('第%d行格式/数值非法，已跳过：%s\n',i,line_str);
                    continue
                end
                
                % 合法：保存到临时矩阵（单位统一成米）
                Lens_valid(end+1,:) = [f D z]*1e-3;  % 自动扩容
                valid_line{end+1}   = line_str;     % 保留原始字符串
            end
            
            % 最终赋回 App 属性
            app.Lens = Lens_valid;

            if isempty(valid_line),  valid_line = {''};  end   % 保证 0×1，避免控件报错
            app.TextArea.Value = valid_line;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %开始仿真
            Update(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %图片选择
            [app.img_name,app.img_path]=uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff;*.gif', '图像文件 (*.jpg,*.png,*.bmp,*.tif,*.gif)';
         '*.jpg;*.jpeg', 'JPEG图像 (*.jpg)';
         '*.png', 'PNG图像 (*.png)';
         '*.bmp', '位图图像 (*.bmp)';
         '*.tif;*.tiff', 'TIFF图像 (*.tif)';
         '*.*', '所有文件 (*.*)'}, ...
        '选择图像文件');  
            wholepath=[app.img_path,app.img_name];
            try
                app.img=imread(wholepath);
            catch
                app.ms=msgbox('未找到该文件！', '警告', 'error');
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.lambda = app.EditField.Value*1e-9;
            
        end

        % Value changed function: umEditField
        function umEditFieldValueChanged(app, event)
            %像素尺寸
            app.pix_size = app.umEditField.Value*1e-6;
            
        end

        % Value changed function: mmEditField
        function mmEditFieldValueChanged(app, event)
            %自由传播距离
            app.free_prop_distance = app.mmEditField.Value*1e-3;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.src_Axes = uiaxes(app.UIFigure);
            title(app.src_Axes, 'Title')
            xlabel(app.src_Axes, 'X')
            ylabel(app.src_Axes, 'Y')
            zlabel(app.src_Axes, 'Z')
            app.dst_Axes = uiaxes(app.UIFigure);
            title(app.dst_Axes, 'Title')
            xlabel(app.dst_Axes, 'X')
            ylabel(app.dst_Axes, 'Y')
            zlabel(app.dst_Axes, 'Z')
            app.Lens_Axes = uiaxes(app.UIFigure);
            title(app.Lens_Axes, 'Title')
            xlabel(app.Lens_Axes, 'X')
            ylabel(app.Lens_Axes, 'Y')
            zlabel(app.Lens_Axes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '参数设置';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '图片选择';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '光源波长';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 632.8;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '像素尺寸um';
            app.umEditField = uieditfield(app.Panel, 'numeric');
            app.umEditField.ValueChangedFcn = createCallbackFcn(app, @umEditFieldValueChanged, true);
            app.umEditField.Value = 5;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '透镜设置';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Value = {'200,150,300'; '100,100,600'};
            app.fmmLabel = uilabel(app.Panel);
            app.fmmLabel.Text = '1列:f/mm';
            app.dmmLabel = uilabel(app.Panel);
            app.dmmLabel.Text = '2列:d/mm';
            app.zmmLabel = uilabel(app.Panel);
            app.zmmLabel.Text = '3列:z/mm';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '绘制图像';
            app.mmEditFieldLabel = uilabel(app.Panel);
            app.mmEditFieldLabel.Text = '自由传播距离mm';
            app.mmEditField = uieditfield(app.Panel, 'numeric');
            app.mmEditField.ValueChangedFcn = createCallbackFcn(app, @mmEditFieldValueChanged, true);
            app.mmEditField.Value = 100;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '菲涅尔衍射与透镜成像模拟';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end